classdef dvn_reverb < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AnalysisTab                     matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        NumDictionaryFilterSliderLabel_4  matlab.ui.control.Label
        NumDictionaryFilterSliderLabel_3  matlab.ui.control.Label
        PreFilterOrder                  matlab.ui.control.NumericEditField
        FilterOrder                     matlab.ui.control.NumericEditField
        NumDictionaryFilters            matlab.ui.control.NumericEditField
        MixingTimeSlider                matlab.ui.control.Slider
        MixingTimemsSliderLabel         matlab.ui.control.Label
        AnalysisLamp                    matlab.ui.control.Lamp
        NumDictionaryFilterSliderLabel_2  matlab.ui.control.Label
        NumDictionaryFilterSliderLabel  matlab.ui.control.Label
        AnalyzeButton                   matlab.ui.control.Button
        LoadRIRButton                   matlab.ui.control.Button
        PreFilterSwitch                 matlab.ui.control.Switch
        WindowSizemsSlider              matlab.ui.control.Slider
        WindowSizemsSliderLabel         matlab.ui.control.Label
        AnalysisLabel                   matlab.ui.control.Label
        analysisGains                   matlab.ui.control.UIAxes
        analysisSpectrogram             matlab.ui.control.UIAxes
        SynthesisTab                    matlab.ui.container.Tab
        GridLayout_2                    matlab.ui.container.GridLayout
        ReverseDampingCheckBox          matlab.ui.control.CheckBox
        ReverseRtCheckBox               matlab.ui.control.CheckBox
        RelativeDampingKnob             matlab.ui.control.Knob
        SynthesisLamp                   matlab.ui.control.Lamp
        SynthesizeButton                matlab.ui.control.Button
        PlayConvolvedTargetButton       matlab.ui.control.Button
        RelativeDampingLabel            matlab.ui.control.Label
        PlayConvolvedModelButton        matlab.ui.control.Button
        PlayModelRIRButton              matlab.ui.control.Button
        PlayTargetRIRButton             matlab.ui.control.Button
        LoadTestSoundButton             matlab.ui.control.Button
        RelativeRTLabel                 matlab.ui.control.Label
        ReverbTimeKnob                  matlab.ui.control.Knob
        DensitypulsessLabel             matlab.ui.control.Label
        DensityKnob                     matlab.ui.control.Knob
        SynthesisLabel                  matlab.ui.control.Label
        SynthesisSpectrogram            matlab.ui.control.UIAxes
        analysisSpectrogram_2           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % general
        fs = 48000; % sampling rate
        % analysis 
        winLen = 2048; % stft windowSize in samples
        hopSize = 1024; % stft hopsize in samples
        nFFT = 2^12; % frequency resolution in samples
        targetRir = []; % container for the target RIR
        modelRir = [];
        tMix = 1; % mixing time in samples
        lpOrder = 10;   % dictionary filter order
        preFilterOrder = 10; % pre filter order
        preProcess = true; % toggle pre whitening
        preFilter = []; % pre filter coeffs
        nFrames = []; % number of STFT frames
        tFrame = []; % frame time
        freq = []; % frequency axis
        specTarget = []; % target spectrogram
        specModel = []; % model spectrogram

        dictFilter = []; % dictionary filter coeffs
        A = []; % dict filter coeffs
        B = []; % dict filter coeffs
        nDict = 10; % number of dictionary filters

        % synthesis
        postProcess = true;
        density = 2000; 
        relativeReverbTime = 1; % length modifier related to the original target RIR length
        relativeDamping = 1; % damping modifier
        pFrame = [];  % frame probabilites
        %pPulse = [];  % pulse probabilities
        k = [];       % pulse locations
        s = [];       % pulse signs
        gFrame = [];  % frame gains
        gPulse = [];  % pulse gains
        f = [];       % pulse filter list
        testSound = [];
        postFilterB = [];
        postFilterA = [];
    end
    
    methods (Access = private)
        
        function runAnalysis(app)
            % TRIM
            startIdx = find(app.targetRir.^2 > 0.001,1);            % find where the signal energy is more -60 dB
            app.targetRir = app.targetRir(startIdx:end);                              % remove silent begining
            % PARAMS
            rir = app.targetRir(app.tMix:end);
            rir = [rir;zeros(app.winLen/2,1)];                      % zero pad
            win = hann(app.winLen);                                 % window function
            app.freq = linspace(0,app.fs/2,app.nFFT/2+1);           % frequency axis

            % PRE & POST FILTER
            app.preFilter = lpc(rir(1:app.winLen) .* win,app.preFilterOrder);
            [app.postFilterB, app.postFilterA] = fitDcBlocker(rir,app.freq,app.fs, 1);
            rir = filter(app.preFilter,1,rir);  % whitening

            % STFT
            L = numel(rir);
            numFrames = floor((L-app.winLen)/app.hopSize+1);    % number of frames
            spec= zeros(app.nFFT/2+1,numFrames);                % init spectrogram matrix
            app.tFrame = zeros(1,numFrames);                    % init time vector (samples)
            pin = 0;                                            % counter for sliding the frame
            lp = zeros(numFrames,app.lpOrder+1);                % init lp filter matrix

            for n = 1:numFrames
                grain = rir(pin+1:pin+app.winLen).*win;         % extract frame and apply window function
                lp(n,:) = lpc(grain, app.lpOrder);              % compute LP filter
                temp = fft(grain, app.nFFT);                        % compute FFT
                spec(:,n) = abs(temp(1:app.nFFT/2+1));              % compute one-sided mag response    
                app.tFrame(n) = pin;                            % save frame time
                pin = pin + app.hopSize;                        % make the hop
            end
        
            app.specTarget = spec;
            app.dictFilter = lp';
            app.nFrames = numFrames;
        end
        
        function solveFilters(app)
            targetResponses = app.specTarget;
            dict = app.dictFilter;
            % Desing Dictionary Filters
            logSubSet = unique(round(logspace(log10(1), log10(size(dict,2)),app.nDict)));
            A = dict(:,logSubSet); 
            numFilters = size(A,2); app.nDict = numFilters;
            B = ones(1,numFilters);
            % normalize energy & compute filter responses
            filterResponses = zeros(app.nFFT/2+1,numFilters);
            for n = 1:numFilters
                ir = impz(B(:,n), A(:,n));
                enrg = sqrt(sum(ir.^2));
                B(:,n) = B(:,n) ./ enrg;
                filterResponses(:,n) = abs(freqz(B(:,n), A(:,n), app.freq, app.fs))';
            end
            app.A = A;
            app.B = B;
            
            % FIT TO TARGET SPECTROGRAM
            numResponses = size(targetResponses,2);
            gNorm = zeros(1,numResponses);
            p = zeros(numFilters, numResponses);
            for n = 1:numResponses
                p(:,n) = lsqnonneg(filterResponses .* abs(freqz(app.postFilterB, app.postFilterA, app.freq, app.fs))', targetResponses(:,n));  % solve the optimization problem
                gNorm(n) = sum(p(:,n));
               
            end
            app.gFrame = gNorm; % save frame gains
            app.pFrame = p./gNorm; % save frame probabilties
        end

        
        function synthesizeModel(app)
            if (app.relativeReverbTime == 0)
                return;
            end
            t = app.tFrame;
            p = app.pFrame;
            g = app.gFrame;
            lenTarget = numel(app.targetRir); % length of the original RIR
            lenModel = round(app.relativeReverbTime * lenTarget);
            
            % GENERATE VELVET NOISE %
            [app.k,app.s] = vn(app.fs, app.density, lenModel);                 % generate pulse locations and sign

            % REMOVE EARLY PART%
            %[t, p, g] = app.removeEarly();

            % RELATIVE DAMPING
            p = app.applyRelativeDamping(p);

            % Interpolate & Assign Pulse filters
            t = round(t .* app.relativeReverbTime);
            pPulse = interpDvnParams(t,p,app.k(:,1)); % query point interpolation
            app.f = assignFiltersGreedy(pPulse); % assign filters based on the probabilities

            % Interpolate Pulse Gains
            app.gPulse = interpDvnParams(t, g, app.k(:,1)); % query point interpolation
        end

        
        function computeModelImpulseResponse(app)
            early = app.targetRir(1:app.tMix-1); % get early part
            if(app.relativeReverbTime == 0)
                app.modelRir = early;
                return;
            end
            gainTimesSign = app.s' .* app.gPulse; % combine sign and gain
            lateModel = convDvn(1,app.k,gainTimesSign,app.f,app.B, app.A);   % compute model impulse response L

            % POST FILTER
            if app.preProcess
                lateModel = filter(1,app.preFilter,lateModel); % inverse pre-filter
            end
     
            lateModel = filter(app.postFilterB, app.postFilterA,lateModel);

            % NORMALIZE ENERGY
            eNorm = rms(app.targetRir(app.tMix:end));
            lateModel = nrmlz(lateModel, 'energy', eNorm); 
            if (app.ReverseRtCheckBox.Value == 1)
                ir = [lateModel; flip(early)];
            else
                ir = [early; lateModel]; % combine early and late parts
            end
            app.modelRir = ir;
        end
        
        function result = offlineConvolution(app, TargetOrModel)
            in = app.testSound;
            switch TargetOrModel
                case 'Target'
                    result = fconv(in, app.targetRir);
                case 'Model'
                    result = fconv(in, app.modelRir);
            end
        end
        
        function relativeRT = rtKnobMapping(~,knobValue)
            if (knobValue >= 6) % factor >= 1
                relativeRT = interp1([6,11],[1,10],knobValue);
            else % factor < 1
                relativeRT = interp1([1,6], [0 1], knobValue);
            end
        end
        
        function [t, p, g, dict, targetSpec] = removeEarly(app)
            [~,closestIndex] = min(abs(app.tFrame-app.tMix));
            t = app.tFrame(closestIndex:end);
            p = app.pFrame(:,closestIndex:end);
            g = app.gFrame(closestIndex:end);
            dict = app.dictFilter(:,closestIndex:end);
            targetSpec = app.specTarget(:,closestIndex:end);

        end
        
        function pMod = applyRelativeDamping(app, p)
            if(app.relativeDamping == 1)
                pMod = p;
                return
            end
            [numDict, numResponses] = size(p);
            pMod = zeros(numDict, numResponses);
            if (app.relativeDamping == 0) % no damping
                pMod = repmat(p(:,1),[1,numResponses]);
            elseif (abs(app.relativeDamping) < 1) % slower damping
                idx = round(numResponses * abs(app.relativeDamping));            % find the number of frames
                pMod  = interp1(linspace(1,numResponses,idx), p(:,1:idx)', 1:numResponses)'; % intepolate to longer time span
            end
        end
        
        function plotSpectroGram(app,s,t, dynamicRange, frequencyRange, timeRange, uifig)
            
            s = db(s); % convert to dB
            s = s - max(max(s)); % normalize to 0 dB
            sur = surf(uifig,t/app.fs,app.freq,s);
            sur.EdgeColor = 'none';
            axis(uifig, 'xy'); axis(uifig, 'tight'); view(uifig,[0,90]); % change to 2 D view
            colormap(uifig,"turbo") % set color map
            cb = colorbar(uifig);
            set(cb,'YTick',-60:20:0,'YTicklabel',{'-60 dB', '-40 dB', '-20 dB', '0 dB'})
            caxis(uifig,dynamicRange)
        
            xlabel(uifig,'Time (s)')
            ylabel(uifig,'Frequency (Hz)')
            xlim(uifig,timeRange)
            ylim(uifig,frequencyRange)
            ax = uifig;
            ax.FontSize = 15;
            set(uifig,'YScale', 'log', 'YTick',[100 1000 10000], 'YTicklabel',{'100'  ,'1k', '10k'});
            grid(uifig,'off');
        end


        function plotTimeResponse(app, data,uifig, scaling)
            t = app.tFrame / app.fs;
            switch scaling
                case 'linear'
                    plot(uifig,t,data, 'k', 'LineWidth', 2);
                case 'dB'
                    plot(uifig,t,20*log10(abs(data)), 'k', 'LineWidth', 2);
            end
            xlabel(uifig,'Time (s)')
            ylabel(uifig, 'gain (dB)')
        end
        
        function [spec, t] = mystft(app, rir)
            nWin = 2*round(app.winLen*0.5 * app.relativeReverbTime);
            nHop = nWin/2;
            win = hann(nWin);                 % window function
            %app.freq = linspace(20, 20000, app.nBins);  % frequency vector
            
            % STFT
            L = numel(rir);
            numFrames = floor((L-nWin)/nHop+1);% number of frames
            spec= zeros(app.nFFT/2+1,numFrames);               % init spectrogram matrix
            t = zeros(1,numFrames);          % init time vector (samples)
        
            pin = 0;                                % counter for sliding the frame
            for n = 1:numFrames
                grain = rir(pin+1:pin+nWin).*win;                 % extract frame and apply window function
                spec(:,n) = (abs(freqz(grain,1,app.freq,app.fs)))';     % compute mag response
                t(n) = pin + round(nWin/2);              % save frame time
                pin = pin + nHop;                                % make the hop
            end
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('../functions')
            app.targetRir = audioread("pori.wav");
            app.testSound = audioread("song.wav");
        end

        % Value changed function: WindowSizemsSlider
        function WindowSizemsSliderValueChanged(app, event)
            app.winLen = 2*round(app.WindowSizemsSlider.Value * 0.5 * 0.001 * app.fs); % save window length internally in samples rounded to nearest even integer
            app.hopSize = app.winLen * 0.5;
            %app.AnalyzeButtonPushed();
        end

        % Button pushed function: LoadRIRButton
        function LoadRIRButtonPushed(app, event)
            [name, path, sts] = uigetfile({'*.wav'; '*.mp3'}, 'File Selector');
            if(sts)
                [app.targetRir, fs_temp] = audioread(fullfile(path, name));
                app.targetRir = app.targetRir(:,1); % only mono
                app.targetRir = resample(app.targetRir,app.fs,fs_temp); % use consistent sample rate
            end
        end

        % Value changed function: PreFilterSwitch
        function PreFilterSwitchValueChanged(app, event)
            app.preProcess = app.PreFilterSwitch.Value;
            app.AnalyzeButtonPushed();
        end

        % Value changed function: DensityKnob
        function DensityKnobValueChanged(app, event)
            app.density = app.DensityKnob.Value;
            app.SynthesizeButtonPushed();
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            % Run the analysis
            app.AnalysisLamp.Color = [1,1,0];
            pause(0.001);
            app.runAnalysis();
            app.solveFilters();
            disp('Analysis complete')
            app.AnalysisLamp.Color = [0,1,0];

            [spec, t] = app.mystft(app.targetRir);
            app.plotSpectroGram(spec, t, [-60 0], [20 20000], [0, numel(app.targetRir)/app.fs], app.analysisSpectrogram);
            app.plotSpectroGram(spec, t, [-60 0], [20 20000], [0, numel(app.targetRir)/app.fs], app.analysisSpectrogram_2);
            app.plotTimeResponse(app.gFrame, app.analysisGains, 'dB')
        end

        % Button pushed function: PlayTargetRIRButton
        function PlayTargetRIRButtonPushed(app, event)
            if (~isempty(app.targetRir))
                soundsc(app.targetRir, app.fs);
            end
        end

        % Button pushed function: PlayModelRIRButton
        function PlayModelRIRButtonPushed(app, event)
            if (~isempty(app.modelRir))
                soundsc(app.modelRir, app.fs);
            end
        end

        % Button pushed function: LoadTestSoundButton
        function LoadTestSoundButtonPushed(app, event)
            [name, path, sts] = uigetfile({'*.wav'; '*.mp3'}, 'File Selector');
            if(sts)
                [app.testSound, fs_temp] = audioread(fullfile(path, name));
                app.testSound = app.testSound(:,1); % only mono
                app.testSound= resample(app.testSound,app.fs,fs_temp); % use consistent sample rate
            end
        end

        % Button pushed function: PlayConvolvedModelButton
        function PlayConvolvedModelButtonPushed(app, event)
            if (~isempty(app.modelRir) && ~isempty(app.testSound))
                convAudio = app.offlineConvolution('Model');
                soundsc(convAudio, app.fs);
            end
        end

        % Button pushed function: PlayConvolvedTargetButton
        function PlayConvolvedTargetButtonPushed(app, event)
            if(~isempty(app.targetRir) && ~isempty(app.testSound))
                convAudio = app.offlineConvolution('Target');
                soundsc(convAudio, app.fs);
            end
        end

        % Button pushed function: SynthesizeButton
        function SynthesizeButtonPushed(app, event)
            app.SynthesisLamp.Color = [1,1,0];
            pause(0.001);
            if (~isempty(app.specTarget))
                app.synthesizeModel();
                app.computeModelImpulseResponse();
                app.SynthesisLamp.Color = [0,1,0];
                % STFT
                tic
                [spec, t] = app.mystft(app.modelRir);
                toc
                app.plotSpectroGram(spec,t, [-60 0], [20 20000], [0, size(app.modelRir,1)/app.fs], app.SynthesisSpectrogram);
                % Rescale X limit
                if(size(app.modelRir,1) > size(app.targetRir,1))
                    xlim(app.analysisSpectrogram_2,[0, size(app.modelRir,1)/app.fs]);
                elseif (size(app.modelRir,1) < size(app.targetRir,1))
                    xlim(app.SynthesisSpectrogram,[0, size(app.targetRir,1)/app.fs]);
                    xlim(app.analysisSpectrogram_2,[0, size(app.targetRir,1)/app.fs]);
                end

            else
                error('Missing target RIR. Run the analysis first.')
            end
        end

        % Value changed function: ReverbTimeKnob
        function ReverbTimeKnobValueChanged(app, event)
            value = app.ReverbTimeKnob.Value;
            app.relativeReverbTime = app.rtKnobMapping(value);
            app.SynthesizeButtonPushed();
        end

        % Value changed function: RelativeDampingKnob
        function RelativeDampingKnobValueChanged(app, event)
            %value = app.RelativeDampingKnob.Value;
            app.relativeDamping = app.RelativeDampingKnob.Value;
            app.SynthesizeButtonPushed(); 
        end

        % Value changed function: MixingTimeSlider
        function MixingTimeValueChanged(app, event)
            app.tMix = round(app.MixingTimeSlider.Value * app.fs * 1/1000)+1;
            app.AnalyzeButtonPushed();
        end

        % Value changed function: FilterOrder
        function FilterOrderSliderValueChanged(app, event)
            app.lpOrder = round(app.FilterOrder.Value);
        end

        % Value changed function: NumDictionaryFilters
        function NumDictionaryFilterSliderValueChanged(app, event)
            app.nDict = app.NumDictionaryFilters.Value;
            
        end

        % Value changed function: PreFilterOrder
        function PreFilterOrderValueChanged(app, event)
            app.preFilterOrder = app.PreFilterOrder.Value;
        end

        % Callback function
        function PostFilterSwitchValueChanged(app, event)
            app.postProcess = app.PostFilterSwitch.Value;
            app.postProcess
        end

        % Callback function
        function CoherenceSliderValueChanged(app, event)
            app.coh = app.CoherenceSlider.Value;
            
        end

        % Value changed function: ReverseRtCheckBox
        function ReverseRtCheckBoxValueChanged(app, event)
            app.gFrame = flip(app.gFrame);
            app.SynthesizeButtonPushed();
        end

        % Value changed function: ReverseDampingCheckBox
        function ReverseDampingCheckBoxValueChanged(app, event)
            app.pFrame = fliplr(app.pFrame);
            app.SynthesizeButtonPushed();            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.GridLayout = uigridlayout(app.AnalysisTab);
            app.analysisSpectrogram = uiaxes(app.GridLayout);
            title(app.analysisSpectrogram, 'Target Spectrogram', 'Interpreter', 'latex')
            xlabel(app.analysisSpectrogram, 'Time (s)', 'Interpreter', 'latex')
            ylabel(app.analysisSpectrogram, 'Frequency (Hz)', 'Interpreter', 'latex')
            zlabel(app.analysisSpectrogram, 'Z', 'Interpreter', 'latex')
            app.analysisGains = uiaxes(app.GridLayout);
            title(app.analysisGains, 'Gain Envelope', 'Interpreter', 'latex')
            xlabel(app.analysisGains, 'Time (s)', 'Interpreter', 'latex')
            ylabel(app.analysisGains, 'Level (dB)', 'Interpreter', 'latex')
            zlabel(app.analysisGains, 'Z', 'Interpreter', 'latex')
            app.AnalysisLabel = uilabel(app.GridLayout);
            app.AnalysisLabel.Text = 'Analysis';
            app.WindowSizemsSliderLabel = uilabel(app.GridLayout);
            app.WindowSizemsSliderLabel.Text = 'Window Size (ms)';
            app.WindowSizemsSlider = uislider(app.GridLayout);
            app.WindowSizemsSlider.Limits = [5 100];
            app.WindowSizemsSlider.MajorTicks = [5 20 40 60 80 100];
            app.WindowSizemsSlider.MajorTickLabels = {'5', '20', '40', '60', '80', '100'};
            app.WindowSizemsSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSizemsSliderValueChanged, true);
            app.WindowSizemsSlider.MinorTicks = [5 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.WindowSizemsSlider.Value = 85;
            app.PreFilterSwitch = uiswitch(app.GridLayout, 'slider');
            app.PreFilterSwitch.ItemsData = [false true];
            app.PreFilterSwitch.ValueChangedFcn = createCallbackFcn(app, @PreFilterSwitchValueChanged, true);
            app.PreFilterSwitch.Value = true;
            app.LoadRIRButton = uibutton(app.GridLayout, 'push');
            app.LoadRIRButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRIRButtonPushed, true);
            app.LoadRIRButton.Text = 'Load RIR';
            app.AnalyzeButton = uibutton(app.GridLayout, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.NumDictionaryFilterSliderLabel = uilabel(app.GridLayout);
            app.NumDictionaryFilterSliderLabel.Text = 'Dictionary Filters';
            app.NumDictionaryFilterSliderLabel_2 = uilabel(app.GridLayout);
            app.NumDictionaryFilterSliderLabel_2.Text = 'Pre-fiter';
            app.AnalysisLamp = uilamp(app.GridLayout);
            app.MixingTimemsSliderLabel = uilabel(app.GridLayout);
            app.MixingTimemsSliderLabel.Text = 'Mixing Time (ms)';
            app.MixingTimeSlider = uislider(app.GridLayout);
            app.MixingTimeSlider.Limits = [0 500];
            app.MixingTimeSlider.ValueChangedFcn = createCallbackFcn(app, @MixingTimeValueChanged, true);
            app.NumDictionaryFilters = uieditfield(app.GridLayout, 'numeric');
            app.NumDictionaryFilters.ValueChangedFcn = createCallbackFcn(app, @NumDictionaryFilterSliderValueChanged, true);
            app.NumDictionaryFilters.Value = 10;
            app.FilterOrder = uieditfield(app.GridLayout, 'numeric');
            app.FilterOrder.ValueChangedFcn = createCallbackFcn(app, @FilterOrderSliderValueChanged, true);
            app.FilterOrder.Value = 2;
            app.PreFilterOrder = uieditfield(app.GridLayout, 'numeric');
            app.PreFilterOrder.ValueChangedFcn = createCallbackFcn(app, @PreFilterOrderValueChanged, true);
            app.PreFilterOrder.Value = 10;
            app.NumDictionaryFilterSliderLabel_3 = uilabel(app.GridLayout);
            app.NumDictionaryFilterSliderLabel_3.Text = 'Num';
            app.NumDictionaryFilterSliderLabel_4 = uilabel(app.GridLayout);
            app.NumDictionaryFilterSliderLabel_4.Text = 'Order';
            app.SynthesisTab = uitab(app.TabGroup);
            app.SynthesisTab.Title = 'Synthesis';
            app.GridLayout_2 = uigridlayout(app.SynthesisTab);
            app.analysisSpectrogram_2 = uiaxes(app.GridLayout_2);
            title(app.analysisSpectrogram_2, 'Target Spectrogram', 'Interpreter', 'latex')
            xlabel(app.analysisSpectrogram_2, 'Time (s)', 'Interpreter', 'latex')
            ylabel(app.analysisSpectrogram_2, 'Frequency (Hz)', 'Interpreter', 'latex')
            zlabel(app.analysisSpectrogram_2, 'Z', 'Interpreter', 'latex')
            app.SynthesisSpectrogram = uiaxes(app.GridLayout_2);
            title(app.SynthesisSpectrogram, 'Model Spectrogram', 'Interpreter', 'latex')
            xlabel(app.SynthesisSpectrogram, 'Time (s)', 'Interpreter', 'latex')
            ylabel(app.SynthesisSpectrogram, 'Frequency (Hz)', 'Interpreter', 'latex')
            zlabel(app.SynthesisSpectrogram, 'Z', 'Interpreter', 'latex')
            app.SynthesisLabel = uilabel(app.GridLayout_2);
            app.SynthesisLabel.Text = 'Synthesis';
            app.DensityKnob = uiknob(app.GridLayout_2, 'continuous');
            app.DensityKnob.Limits = [1 2000];
            app.DensityKnob.MajorTicks = [1 400 800 1200 1600 2000];
            app.DensityKnob.ValueChangedFcn = createCallbackFcn(app, @DensityKnobValueChanged, true);
            app.DensityKnob.Value = 2000;
            app.DensitypulsessLabel = uilabel(app.GridLayout_2);
            app.DensitypulsessLabel.Text = 'Density (pulses/s)';
            app.ReverbTimeKnob = uiknob(app.GridLayout_2, 'continuous');
            app.ReverbTimeKnob.Limits = [1 11];
            app.ReverbTimeKnob.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11];
            app.ReverbTimeKnob.MajorTickLabels = {'0', '0.2', '0.4', '0.6', '0.8', '1', '2', '4', '6', '8', '10'};
            app.ReverbTimeKnob.ValueChangedFcn = createCallbackFcn(app, @ReverbTimeKnobValueChanged, true);
            app.ReverbTimeKnob.Value = 6;
            app.RelativeRTLabel = uilabel(app.GridLayout_2);
            app.RelativeRTLabel.Text = 'Relative RT';
            app.LoadTestSoundButton = uibutton(app.GridLayout_2, 'push');
            app.LoadTestSoundButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTestSoundButtonPushed, true);
            app.LoadTestSoundButton.Text = 'Load Test Sound';
            app.PlayTargetRIRButton = uibutton(app.GridLayout_2, 'push');
            app.PlayTargetRIRButton.ButtonPushedFcn = createCallbackFcn(app, @PlayTargetRIRButtonPushed, true);
            app.PlayTargetRIRButton.Text = 'Play Target RIR';
            app.PlayModelRIRButton = uibutton(app.GridLayout_2, 'push');
            app.PlayModelRIRButton.ButtonPushedFcn = createCallbackFcn(app, @PlayModelRIRButtonPushed, true);
            app.PlayModelRIRButton.Text = 'Play Model RIR';
            app.PlayConvolvedModelButton = uibutton(app.GridLayout_2, 'push');
            app.PlayConvolvedModelButton.ButtonPushedFcn = createCallbackFcn(app, @PlayConvolvedModelButtonPushed, true);
            app.PlayConvolvedModelButton.Text = 'Play Convolved Model';
            app.RelativeDampingLabel = uilabel(app.GridLayout_2);
            app.RelativeDampingLabel.Text = 'Relative Damping';
            app.PlayConvolvedTargetButton = uibutton(app.GridLayout_2, 'push');
            app.PlayConvolvedTargetButton.ButtonPushedFcn = createCallbackFcn(app, @PlayConvolvedTargetButtonPushed, true);
            app.PlayConvolvedTargetButton.Text = 'Play Convolved Target';
            app.SynthesizeButton = uibutton(app.GridLayout_2, 'push');
            app.SynthesizeButton.ButtonPushedFcn = createCallbackFcn(app, @SynthesizeButtonPushed, true);
            app.SynthesizeButton.Text = 'Synthesize';
            app.SynthesisLamp = uilamp(app.GridLayout_2);
            app.RelativeDampingKnob = uiknob(app.GridLayout_2, 'continuous');
            app.RelativeDampingKnob.Limits = [0 1];
            app.RelativeDampingKnob.ValueChangedFcn = createCallbackFcn(app, @RelativeDampingKnobValueChanged, true);
            app.RelativeDampingKnob.Value = 1;
            app.ReverseRtCheckBox = uicheckbox(app.GridLayout_2);
            app.ReverseRtCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReverseRtCheckBoxValueChanged, true);
            app.ReverseRtCheckBox.Text = 'Reverse';
            app.ReverseDampingCheckBox = uicheckbox(app.GridLayout_2);
            app.ReverseDampingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReverseDampingCheckBoxValueChanged, true);
            app.ReverseDampingCheckBox.Text = 'Reverse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dvn_reverb

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end